classdef EvcsPlanner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MainGrid                     matlab.ui.container.GridLayout
        OutputPanel                  matlab.ui.container.Panel
        OutputGrid                   matlab.ui.container.GridLayout
        OutputBoxPanel               matlab.ui.container.Panel
        OutputBoxGrid                matlab.ui.container.GridLayout
        OutputDisplay                matlab.ui.control.HTML
        OutputText                   matlab.ui.control.Label
        OutputLabel                  matlab.ui.control.Label
        MapWorkspacePanel            matlab.ui.container.Panel
        MapWorkspaceGrid             matlab.ui.container.GridLayout
        MapWorkspaceLabel            matlab.ui.control.Label
        ControlsPanel                matlab.ui.container.Panel
        ControlsPanelGrid            matlab.ui.container.GridLayout
        ControlsLabel                matlab.ui.control.Label
        ControlsGrid                 matlab.ui.container.GridLayout
        PartitionButtonsGrid         matlab.ui.container.GridLayout
        SettingsButton               matlab.ui.control.Button
        CreateBusPartitionsButton    matlab.ui.control.Button
        PlaceSubstationFeederButton  matlab.ui.control.Button
        RegionSelectGrid             matlab.ui.container.GridLayout
        RegionSelectionDropDown      matlab.ui.control.DropDown
        RegionLabel                  matlab.ui.control.Label
        SetCoverageAreaButton        matlab.ui.control.Button
        RegionButtonsGrid            matlab.ui.container.GridLayout
        AddRegionButton              matlab.ui.control.Button
        LoadRegionButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Terms used in this app:
        %   region         a saved rectangle of the map (RegionLimits.mat) whose
        %                  dwellings are loaded with Load
        %   coverage area  the polygon drawn inside a region that the feeder serves

        % ---- Map
        MapAxes              matlab.graphics.axis.GeographicAxes  % the app's map
        MapAxesPanel         % borderless panel holding the map (lets it fill the space)
        IsClampingView = false  % guard so clampMapView doesn't re-trigger itself
        AusBoundsBox         % dotted red rectangle around Australia (drawBoundsBox)
        RegionBoundsBox      % dotted red rectangle around the loaded region

        % ---- Region and its dwellings
        RegionTable          % saved regions: LatMin, LatMax, LonMin, LonMax, one row per name
        DwellingTable        % Latitude and Longitude of every dwelling in the loaded region
        IncludeAliasAddresses = false % count G-NAF alias addresses (alternative names for an existing address) as dwellings

        % ---- Coverage area
        CoverageLatLon       % N x 2 [lat lon] vertices of the coverage area (first point repeated at the end)
        CoverageShape        % the same polygon as a geopolyshape
        CoveragePlot         % its polygon on the map

        % ---- Substation
        SubstationLatLon     % [lat lon] of the substation (IEEE node 0)
        SubstationMarker     % its marker on the map

        % ---- Bus layout
        LayoutOptions               % bus-layout settings (busLayout.defaultOptions), edited with the settings cog
        LayoutResult                % last Create Bus Partitions run: struct with cov, tree, res, opts
        LayoutPlots = gobjects(0)   % map graphics of that layout (deleted before redrawing)
        SettingsDialog              % the settings dialog window while it is open

        % ---- Output panel resizing
        IsResizingOutput = false    % true while the top edge of the Output panel is being dragged
        OutputMinHeight = 60        % smallest Output panel height while dragging (px)
        MapMinHeight = 200          % smallest height left for the map and controls (px)
        SplitterHeight = 6          % height of the splitter bar above the Output panel (px)
        OutputSplitter              % the splitter bar (created in startupFcn)

        % ---- Files (see setupPaths.m for the folder layout)
        AppDir               % folder holding EvcsPlanner.mlapp
        RegionFile           % RegionLimits.mat
        GnafMasterFile       % data/gnaf_master.mat (culled G-NAF, see loadGnafRegion)
        CacheDir             % data/cache: one .mat of dwellings per loaded region

        % ---- Constants
        AusLatLim = [-44 -10]   % latitude range of Australia (initial view, home button, bounds box)
        AusLonLim = [112 154]   % longitude range of Australia
        NavLatLim = [-56 4]     % hard pan/zoom limits: Australia plus a wide margin, so
        NavLonLim = [92 174]    %   coastal cities (e.g. Sydney) can be centred on screen
        BoundsBoxColour = [0.72 0.33 0.33]  % low-contrast red of the dotted bounds boxes
        MarginFrac = 0.05       % margin added around a region or coverage area when zooming to it
        Colours = struct(...
            'DarkTeal', [0, 78, 100]/255,...
            'Lavender', [153, 93, 129]/255,...
            'Tan', [241, 222, 222]/255,...
            'Yellow', [236, 164, 0]/255,...
            'LightBlue', [119, 166, 182]/255)
    end

    methods (Access = private)

        % ---- Output box ----------------------------------------------------

        function setOutput(app, msg)
            % Show a message in the Output box
            app.OutputText.Text = msg;                    % plain-text copy (also the fallback display)
            app.OutputDisplay.Data = char(string(msg));   % rounded box (ui/outputBox.html)
        end

        % ---- Map view ------------------------------------------------------

        function fillMapAxesPanel(app)
            % Make the map fill its panel edge to edge (re-applied on every resize)
            gx = app.MapAxes;
            gx.Units = 'normalized';
            gx.PositionConstraint = 'innerposition';
            gx.InnerPosition = [0 0 1 1];
        end

        function h = drawBoundsBox(app, latLim, lonLim)
            % Subtle dotted red rectangle around a lat/lon box on the map
            % (Australia, or a loaded region). Each edge is drawn as many short
            % segments so it follows its line of latitude or longitude exactly.
            % It ignores the mouse, so clicks and pan/zoom pass straight through.
            n = 50;
            la = linspace(latLim(1), latLim(2), n);
            lo = linspace(lonLim(1), lonLim(2), n);
            lat = [la, repmat(latLim(2), 1, n), fliplr(la), repmat(latLim(1), 1, n)];
            lon = [repmat(lonLim(1), 1, n), lo, repmat(lonLim(2), 1, n), fliplr(lo)];
            h = geoplot(app.MapAxes, lat, lon, ':', 'Color', app.BoundsBoxColour, ...
                'LineWidth', 1.5, 'HitTest', 'off', 'PickableParts', 'none');
        end

        function clampMapView(app)
            % Keep the map view inside the navigation bounds (app.NavLatLim /
            % NavLonLim: Australia plus a wide margin). Called whenever the map
            % is panned, zoomed or resized.
            if app.IsClampingView
                return
            end
            app.IsClampingView = true;
            try
                gx = app.MapAxes;
                [latlim, lonlim] = geolimits(gx);
                tol = 1.001;

                % zoomed out further than the navigation bounds: snap back
                if diff(latlim) > tol*diff(app.NavLatLim) && diff(lonlim) > tol*diff(app.NavLonLim)
                    geolimits(gx, app.NavLatLim, app.NavLonLim);
                    [latlim, lonlim] = geolimits(gx);
                end

                % no panning beyond the navigation bounds
                c = gx.MapCenter;
                newLat = clampCentre(c(1), latlim, app.NavLatLim);
                newLon = clampCentre(c(2), lonlim, app.NavLonLim);
                if abs(newLat - c(1)) > 1e-6 || abs(newLon - c(2)) > 1e-6
                    gx.MapCenter = [newLat newLon];
                end
            catch
                % never let a clamping problem break the app
            end
            app.IsClampingView = false;

            function v = clampCentre(v, viewLim, boundLim)
                % shift the centre v so viewLim stays within boundLim
                below = v - viewLim(1);      % distance from centre to lower edge
                above = viewLim(2) - v;      % distance from centre to upper edge
                lo = boundLim(1) + below;
                hi = boundLim(2) - above;
                if lo > hi
                    v = mean(boundLim);      % view is wider than the bounds: centre it
                else
                    v = min(max(v, lo), hi);
                end
            end
        end

        function [latlim, lonlim] = padLimits(app, latlim, lonlim)
            % Widen lat/lon limits by app.MarginFrac on every side.
            latlim = latlim + [-1 1] * app.MarginFrac * diff(latlim);
            lonlim = lonlim + [-1 1] * app.MarginFrac * diff(lonlim);
        end

        % ---- Regions -------------------------------------------------------

        function refreshRegionList(app)
            % Fill the region drop-down from app.RegionTable (nothing selected).
            app.RegionSelectionDropDown.Items = [''; string(app.RegionTable.Properties.RowNames)];
            app.RegionSelectionDropDown.Value = '';
        end

        function clearWorkspace(app)
            % Forget the coverage area, substation and layout (used when a new
            % region is loaded, since they belong to the old one).
            app.CoverageLatLon = [];
            app.CoverageShape = [];
            app.CoveragePlot = [];
            app.SubstationLatLon = [];
            app.SubstationMarker = [];
            app.LayoutResult = [];
            app.LayoutPlots = gobjects(0);
        end

        % ---- Picking points in a separate map window -------------------------

        function [fig, gx] = openPickerFigure(app, titleText)
            % Open a map window matching the app's current view, for ginput.
            fig = figure;
            gx = geoaxes(fig, Basemap="streets-dark");
            [latlim, lonlim] = geolimits(app.MapAxes);
            geolimits(gx, latlim, lonlim);
            hold(gx, 'on');
            geolimits(gx, 'manual');
            title(gx, titleText);
        end

        function latLon = clickPoint(~, fig)
            % One mouse click in the picker window fig, as [lat lon].
            % Returns [] (and closes the window) if it was closed instead.
            try
                [lat, lon] = ginput(1);
            catch
                lat = [];
            end
            if isempty(lat)
                if isvalid(fig)
                    close(fig);
                end
                latLon = [];
            else
                latLon = [lat lon];
            end
        end

        function polygonLatLon = pickPolygon(app)
            % Let the user click out the coverage area in a picker window.
            % Returns the closed polygon as N x 2 [lat lon] (first point
            % repeated at the end), or [] if the window was closed first.
            [fig, gx] = openPickerFigure(app, 'Click to place the first point of the coverage area');
            closeTolFrac = 0.01;   % a click this close to the first point (fraction of the view) closes the area

            first = clickPoint(app, fig);
            if isempty(first)
                polygonLatLon = [];
                return
            end
            polygonLatLon = first;
            geoscatter(gx, first(1), first(2), 'filled', 'MarkerFaceColor', app.Colours.Yellow);
            title(gx, {'Click to add points to the coverage area', ...
                       'Click the yellow first point again to close the area'});

            while true
                p = clickPoint(app, fig);
                if isempty(p)
                    polygonLatLon = [];
                    return
                end
                [latlim, lonlim] = geolimits(gx);
                isClosing = abs(p(1) - first(1)) <= closeTolFrac * diff(latlim) && ...
                            abs(p(2) - first(2)) <= closeTolFrac * diff(lonlim);

                prev = polygonLatLon(end, :);
                geoplot(gx, [prev(1) p(1)], [prev(2) p(2)], 'Color', app.Colours.DarkTeal);
                geoscatter(gx, p(1), p(2), 'filled', 'MarkerFaceColor', app.Colours.DarkTeal);

                if isClosing
                    polygonLatLon(end+1, :) = first;
                    break
                end
                polygonLatLon(end+1, :) = p; %#ok<AGROW>
            end
            close(fig);
        end

        function h = plotCoverage(app, gx)
            % Draw the coverage area on the geographic axes gx.
            h = geoplot(gx, app.CoverageShape, ...
                'FaceColor', app.Colours.LightBlue, ...
                'FaceAlpha', 0.3, ...
                'EdgeColor', app.Colours.DarkTeal, ...
                'LineWidth', 2);
        end

        % ---- Bus layout ----------------------------------------------------

        function tree = feederTree(app)
            % The IEEE-33 feeder as a tree (busLayout.buildFeederTree), sized
            % with the current ADMD setting (tree.dwellings).
            [branches, ~, buses] = networkData33Bus();
            tree = busLayout.buildFeederTree(branches, buses, app.LayoutOptions);
        end

        function drawBusLayout(app, cov, tree, res)
            % Draw a busLayout.generate result on the app's map in the same
            % style as busLayout.plotLayout: zones filled with a light tint of
            % their feeder section's colour (white borders), dark feeder lines,
            % buses coloured by section with black outlines and black numbers,
            % and a legend. Any layout drawn earlier is removed first.
            gx = app.MapAxes;
            if ~isempty(app.LayoutPlots)
                delete(app.LayoutPlots(isvalid(app.LayoutPlots)));
            end
            app.LayoutPlots = gobjects(0);
            hold(gx, 'on');

            % --- Colours: one per feeder section (main feeder, then each lateral)
            [nodeColour, sectionOf, palette] = busLayout.feederSectionColours(tree);
            tint = @(c) 0.25 * c + 0.75;                  % pale version for zone fills

            % --- Zones. res.zone is a raster holding one bus number per pixel.
            % Each zone's outline is traced with contourc (the 0.5 level of a
            % 0/1 mask of that zone), converted to lat/lon and drawn as a
            % filled polygon.
            [ny, nx] = size(cov.mask);
            xc = ((0:nx + 1) - 0.5) * cov.pixelSize_km;   % pixel centres incl. a
            yc = ((0:ny + 1) - 0.5) * cov.pixelSize_km;   % one-pixel empty border
            for b = tree.zoneIdx
                m = zeros(ny + 2, nx + 2);
                m(2:end-1, 2:end-1) = double(res.zone == tree.nodeNo(b) & cov.mask);
                C = contourc(xc, yc, m, [0.5 0.5]);
                % contourc output: columns [level; nPoints] then nPoints of [x; y]
                lat = []; lon = []; k = 1;
                while k < size(C, 2)
                    n = C(2, k);
                    [la, lo] = busLayout.toLatLon(cov, C(:, k + 1:k + n)');
                    if sum((lo([2:end 1]) - lo) .* (la([2:end 1]) + la)) < 0
                        la = flipud(la); lo = flipud(lo);    % outer boundaries clockwise
                    end
                    lat = [lat; la; NaN]; lon = [lon; lo; NaN]; %#ok<AGROW>
                    k = k + n + 1;
                end
                if isempty(lat), continue; end
                app.LayoutPlots(end+1) = geoplot(gx, geopolyshape(lat, lon), ...
                    'FaceColor', tint(nodeColour(b, :)), 'FaceAlpha', 0.6, ...
                    'EdgeColor', 'w', 'LineWidth', 1);
            end

            % --- Feeder lines (parent -> child) as one NaN-separated line.
            br = tree.br;
            lineLat = [res.lat(br(:, 1)) res.lat(br(:, 2)) nan(size(br, 1), 1)]';
            lineLon = [res.lon(br(:, 1)) res.lon(br(:, 2)) nan(size(br, 1), 1)]';
            app.LayoutPlots(end+1) = geoplot(gx, lineLat(:), lineLon(:), '-', ...
                'Color', [0.1 0.1 0.1], 'LineWidth', 1.6);

            % --- Buses 1..32, one marker set per section (these also feed the
            % legend), with black bus numbers beside them.
            legendHandles = gobjects(0); legendNames = {};
            for s = 1:size(palette, 1)
                b = tree.zoneIdx(sectionOf(tree.zoneIdx) == s);
                h = geoscatter(gx, res.lat(b), res.lon(b), 42, palette(s, :), 'filled', ...
                    'MarkerEdgeColor', 'k', 'LineWidth', 0.8);
                app.LayoutPlots(end+1) = h;
                nums = tree.nodeNo(b);
                if s == 1, kind = 'main feeder'; else, kind = 'lateral'; end
                legendHandles(end+1) = h; %#ok<AGROW>
                legendNames{end+1} = sprintf('%s %d-%d', kind, min(nums), max(nums)); %#ok<AGROW>
            end
            for b = tree.zoneIdx
                app.LayoutPlots(end+1) = text(gx, res.lat(b), res.lon(b), ...
                    sprintf('  %d', tree.nodeNo(b)), 'Color', 'k', 'FontSize', 8, ...
                    'FontWeight', 'bold', 'VerticalAlignment', 'bottom');
            end

            % --- Substation: keep the app's marker on top and in the legend.
            if ~isempty(app.SubstationMarker) && isvalid(app.SubstationMarker)
                uistack(app.SubstationMarker, 'top');
                legendHandles(end+1) = app.SubstationMarker;
                legendNames{end+1} = 'substation (node 0)';
            end
            lg = legend(gx, legendHandles, legendNames, 'Location', 'southeast', ...
                'TextColor', 'k', 'Color', [1 1 1], 'EdgeColor', [0.6 0.6 0.6], 'AutoUpdate', 'off');
            app.LayoutPlots(end+1) = lg;
        end

        function settingsApplied(app, opts)
            % Called by the settings dialog when Apply is pressed.
            app.LayoutOptions = opts;
            if opts.usePSO, pso = 'on'; else, pso = 'off'; end
            setOutput(app, sprintf(['Bus partition settings updated (PSO refinement %s). ' ...
                'They apply the next time you press Create Bus Partitions.'], pso));
        end

        % ---- Drag-to-resize for the Output panel ------------------------------
        % The splitter bar (row 2 of app.MainGrid) starts a resize when pressed.
        % Only then are the window's motion / button-up callbacks installed,
        % and they are removed again when the mouse is released.  This matters:
        % MATLAB turns off an axes' built-in pan and zoom for as long as any
        % WindowButton...Fcn callback is set on its figure, so permanent
        % callbacks would leave the map unable to pan or zoom.
        % The Output panel (row 3) is at the bottom of the window and
        % UIFigure.CurrentPoint is measured from the bottom-left corner, so the
        % new panel height is simply the mouse height minus half the bar.

        function startOutputResize(app)
            % Splitter pressed: follow the mouse until the button is released.
            app.IsResizingOutput = true;
            app.UIFigure.Pointer = 'top';
            app.UIFigure.WindowButtonMotionFcn = @(~,~) onOutputResizeMove(app);
            app.UIFigure.WindowButtonUpFcn     = @(~,~) stopOutputResize(app);
        end

        function onOutputResizeMove(app)
            % During the drag: the Output panel's top edge follows the mouse,
            % keeping both the panel and the map above a minimum height.
            y = app.UIFigure.CurrentPoint(2);
            figH = app.UIFigure.Position(4);
            h = y - app.SplitterHeight / 2;
            h = min(max(h, app.OutputMinHeight), figH - app.MapMinHeight - app.SplitterHeight);
            app.MainGrid.RowHeight{3} = round(h);
        end

        function stopOutputResize(app)
            % Mouse released: remove the drag callbacks (turns the map's pan
            % and zoom back on).
            app.IsResizingOutput = false;
            app.UIFigure.WindowButtonMotionFcn = '';
            app.UIFigure.WindowButtonUpFcn = '';
            app.UIFigure.Pointer = 'arrow';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Code folders on the path and file locations (layout: setupPaths.m)
            app.AppDir = fileparts(mfilename("fullpath"));
            addpath(app.AppDir);
            setupPaths();
            app.RegionFile     = fullfile(app.AppDir, "RegionLimits.mat");
            app.GnafMasterFile = fullfile(app.AppDir, "data", "gnaf_master.mat");
            app.CacheDir       = fullfile(app.AppDir, "data", "cache");

            % Create geographic axes inside a borderless panel in row 2, so the map can
            % fill the whole area (placed directly in the grid, the axes keep wide
            % margins reserved for their hidden tick labels)
            app.MapAxesPanel = uipanel(app.MapWorkspaceGrid, 'BorderType', 'none', ...
                'BackgroundColor', app.MapWorkspaceGrid.BackgroundColor, 'AutoResizeChildren', 'off');
            app.MapAxesPanel.Layout.Row = 2;
            app.MapAxesPanel.Layout.Column = 1;
            app.MapAxes = geoaxes(app.MapAxesPanel, ...
                NextPlot="add",Basemap="streets-dark");
            fillMapAxesPanel(app);
            app.MapAxesPanel.SizeChangedFcn = @(~,~) fillMapAxesPanel(app);

            app.MapAxes.LatitudeAxis.Visible = "off";
            app.MapAxes.LongitudeAxis.Visible = "off";

            % Allow panning and zooming (since R2024a); clampMapView keeps the
            % view within app.NavLatLim / NavLonLim (Australia plus a wide margin)
            app.MapAxes.InteractionOptions.PanSupported = "on";
            app.MapAxes.InteractionOptions.ZoomSupported = "on";

            % Initial map view: Australia
            geolimits(app.MapAxes, app.AusLatLim, app.AusLonLim);

            % Home button returns to Australia; pan/zoom limits are the wider
            % navigation bounds (see clampMapView)
            app.MapAxes.InteractionOptions.RestoredLatitudeLimits  = app.AusLatLim;
            app.MapAxes.InteractionOptions.RestoredLongitudeLimits = app.AusLonLim;
            app.MapAxes.LatitudeAxis.LimitsChangedFcn  = @(~,~) clampMapView(app);
            app.MapAxes.LongitudeAxis.LimitsChangedFcn = @(~,~) clampMapView(app);

            % Subtle dotted red box marking Australia
            app.AusBoundsBox = drawBoundsBox(app, app.AusLatLim, app.AusLonLim);

            % Fill the region drop-down
            S = load(app.RegionFile, "regions");
            app.RegionTable = S.regions;
            refreshRegionList(app);

            % Output box: rounded display (ui/outputBox.html) if available, otherwise the plain label
            htmlFile = fullfile(app.AppDir, "ui", "outputBox.html");
            if isfile(htmlFile)
                app.OutputDisplay.HTMLSource = htmlFile;
                app.OutputText.Visible = 'off';
            else
                app.OutputDisplay.Visible = 'off';
            end

            % Bus-layout settings (edited with the settings cog)
            app.LayoutOptions = busLayout.defaultOptions('verbose', false);

            % Output panel height can be changed by dragging a thin splitter bar
            % between the map and the Output panel. The bar gets its own row in
            % MainGrid (row 2); the Output panel moves down to row 3.
            % (No permanent window mouse callbacks: while one is set, MATLAB
            % switches off the map's pan and zoom - see startOutputResize.)
            app.MainGrid.RowHeight = {app.MainGrid.RowHeight{1}, app.SplitterHeight, app.MainGrid.RowHeight{2}};
            app.OutputPanel.Layout.Row = 3;
            app.OutputSplitter = uipanel(app.MainGrid, 'BorderType', 'none', ...
                'BackgroundColor', [0.40 0.40 0.40], ...
                'ButtonDownFcn', @(~,~) startOutputResize(app));
            app.OutputSplitter.Layout.Row = 2;
            app.OutputSplitter.Layout.Column = [1 2];
            app.OutputLabel.Tooltip = 'Drag the grey bar above this panel to resize it';
        end

        % Button pushed function: LoadRegionButton
        function LoadRegionButtonPushed(app, event)
            region = string(app.RegionSelectionDropDown.Value);

            % nothing selected: just return the map to Australia
            if region == ""
                geolimits(app.MapAxes, app.AusLatLim, app.AusLonLim);
                return
            end

            % clear the previous region (and free its dwellings first, so two
            % regions are never held in memory at once)
            cla(app.MapAxes);
            app.AusBoundsBox = drawBoundsBox(app, app.AusLatLim, app.AusLonLim);   % cla removed it
            clearWorkspace(app);
            app.DwellingTable = [];

            % zoom to the region (plus a margin)
            regionRow = app.RegionTable(char(region), :);
            regionLatLim = [regionRow.LatMin regionRow.LatMax];
            regionLonLim = [regionRow.LonMin regionRow.LonMax];
            [viewLatLim, viewLonLim] = padLimits(app, regionLatLim, regionLonLim);

            % mark the region with a dotted box (replacing the previous region's)
            if ~isempty(app.RegionBoundsBox) && isvalid(app.RegionBoundsBox)
                delete(app.RegionBoundsBox);
            end
            app.RegionBoundsBox = drawBoundsBox(app, regionLatLim, regionLonLim);
            geolimits(app.MapAxes, viewLatLim, viewLonLim);

            % dwellings: from the region's cache file if it exists, otherwise
            % cut from the G-NAF master (coordinates only) and cached
            cacheFile = fullfile(app.CacheDir, region + ".mat");
            if isfile(cacheFile)
                S = load(cacheFile, 'T');
                T = S.T;
            else
                % (if the master file is missing it is rebuilt from the .psv first;
                %  if that is missing too, the error says which file to add where)
                try
                    T = loadGnafRegion(app.GnafMasterFile, regionLatLim, regionLonLim, [], cacheFile);
                catch err
                    uialert(app.UIFigure, err.message, 'Could not load region');
                    setOutput(app, "Load failed: " + region);
                    app.RegionSelectionDropDown.Value = '';
                    return
                end
            end

            % optionally drop alias addresses (duplicate names for an existing address)
            if ~isempty(T) && ~app.IncludeAliasAddresses && ismember('IsAlias', T.Properties.VariableNames)
                T = T(~T.IsAlias, :);
            end

            % keep only the coordinates (address text isn't needed)
            if ~isempty(T)
                T = T(:, {'Latitude', 'Longitude'});
            end
            app.DwellingTable = T;

            % report what was loaded
            limitsText = sprintf('lat %.4f to %.4f, lon %.4f to %.4f', ...
                regionLatLim(1), regionLatLim(2), regionLonLim(1), regionLonLim(2));
            if isempty(app.DwellingTable)
                setOutput(app, sprintf('No dwellings found in %s (%s)', region, limitsText));
            else
                nText = regexprep(sprintf('%d', height(app.DwellingTable)), '(\d)(?=(\d{3})+$)', '$1,');   % thousands separators
                setOutput(app, sprintf('Successfully loaded %s dwellings in %s (%s)', nText, region, limitsText));
            end

            % reset dropdown menu
            app.RegionSelectionDropDown.Value = '';
        end

        % Button pushed function: SetCoverageAreaButton
        function SetCoverageAreaButtonPushed(app, event)
            % a region must be loaded so the dwellings in the area can be counted
            if isempty(app.DwellingTable)
                setOutput(app, 'Load a region before setting a coverage area.');
                return
            end

            % let the user click out the polygon (any existing coverage area
            % is kept if they close the window instead)
            polygonLatLon = pickPolygon(app);
            if isempty(polygonLatLon)
                setOutput(app, 'Coverage area cancelled.');
                return
            end

            % Count dwellings in the drawn polygon before accepting it.
            % If the count fails, the area is too large to process, so it is rejected
            % (any existing coverage area is kept).
            try
                count = countDwellings(app.DwellingTable, polygonLatLon);
            catch err
                uialert(app.UIFigure, ['The area you drew is too large to count the dwellings inside it. ' ...
                    'Please draw a smaller coverage area.'], 'Coverage area too large');
                setOutput(app, sprintf('Coverage area rejected: too large to process (%s)', err.message));
                return
            end

            % Replace any existing coverage area on the map
            if ~isempty(app.CoveragePlot) && isvalid(app.CoveragePlot)
                delete(app.CoveragePlot);
            end
            app.CoverageLatLon = polygonLatLon;
            app.CoverageShape = geopolyshape(polygonLatLon(:,1), polygonLatLon(:,2));
            app.CoveragePlot = plotCoverage(app, app.MapAxes);

            % Zoom to the coverage area (plus a margin)
            [latlim, lonlim] = bounds(app.CoverageShape);
            [latlim, lonlim] = padLimits(app, latlim, lonlim);
            geolimits(app.MapAxes, latlim, lonlim);

            % Compare with the number of dwellings the feeder can serve
            tree = feederTree(app);
            setOutput(app, sprintf('Coverage area contains %d / %d dwellings*', count, tree.dwellings));
        end

        % Button pushed function: PlaceSubstationFeederButton
        function PlaceSubstationFeederButtonPushed(app, event)
            % a coverage area must exist before the substation can be placed
            if isempty(app.CoverageLatLon)
                setOutput(app, 'Set a coverage area before placing the substation.');
                return
            end

            % picker window showing the coverage area
            [fig, gx] = openPickerFigure(app, 'Click inside the coverage area to place the substation');
            plotCoverage(app, gx);

            % repeat until the click falls inside the coverage area (points on
            % the edge count as inside)
            while true
                p = clickPoint(app, fig);
                if isempty(p)
                    setOutput(app, 'Substation placement cancelled.');
                    return
                end
                if inpolygon(p(1), p(2), app.CoverageLatLon(:,1), app.CoverageLatLon(:,2))
                    break
                end
                title(gx, {'Point is outside the coverage area', ...
                           'Click inside the coverage area to place the substation'}, ...
                      'Color', app.Colours.Yellow);
            end
            close(fig);

            % replace the old substation marker (only now that a valid point
            % was chosen, so cancelling keeps the old substation)
            if ~isempty(app.SubstationMarker) && isvalid(app.SubstationMarker)
                delete(app.SubstationMarker);
            end
            app.SubstationLatLon = p;
            app.SubstationMarker = geoscatter(app.MapAxes, p(1), p(2), 80, ...
                'filled', ...
                'MarkerFaceColor', app.Colours.Yellow, ...
                'MarkerEdgeColor', app.Colours.Tan, ...
                'LineWidth', 1.5);

            setOutput(app, sprintf('Substation placed at (%.5f, %.5f)', p(1), p(2)));
        end

        % Button pushed function: CreateBusPartitionsButton
        function CreateBusPartitionsButtonPushed(app, event)
            % Lay the IEEE-33 feeder over the coverage area: place all 33 buses so
            % each bus's electrical zone (the area closest to it) captures the same
            % share of the area's dwellings as that bus has of the feeder's load
            % (dwellings stand in for load). The method
            % is the busLayout package; +busLayout/generate.m explains it.

            % a coverage area and the substation are both needed first
            if isempty(app.CoverageLatLon)
                setOutput(app, 'Set a coverage area before creating bus partitions.');
                return
            end
            if isempty(app.SubstationLatLon)
                setOutput(app, 'Place the substation before creating bus partitions.');
                return
            end

            % Settings: everything from the settings cog dialog (app.LayoutOptions,
            % documented in +busLayout/defaultOptions.m - PSO refinement is switched
            % on there), plus the clicked substation point, which fixes node 0
            opts = app.LayoutOptions;
            opts.substationLatLon = app.SubstationLatLon;

            % feeder (node 0 = substation, buses 1..32 carry the load)
            tree = feederTree(app);

            if opts.usePSO
                msg = 'Placing buses, then PSO refinement (about a minute)...';
            else
                msg = 'Placing buses (about 15-20 seconds)...';
            end
            dlg = uiprogressdlg(app.UIFigure, 'Title', 'Create Bus Partitions', ...
                'Message', msg, 'Indeterminate', 'on');
            try
                % rasterise the drawn coverage polygon
                cov = busLayout.coverageFromPolygon(app.CoverageLatLon(:,1), app.CoverageLatLon(:,2), opts);

                % the dwellings inside it stand in for load: each zone's share is
                % its share of these dwellings (+busLayout/addDwellings.m)
                [~, inside] = countDwellings(app.DwellingTable, app.CoverageLatLon);
                cov = busLayout.addDwellings(cov, app.DwellingTable.Latitude(inside), ...
                    app.DwellingTable.Longitude(inside), opts);

                % place the buses
                res = busLayout.generate(cov, tree, opts);
            catch err
                close(dlg);
                uialert(app.UIFigure, err.message, 'Bus layout failed');
                setOutput(app, sprintf('Bus layout failed: %s', err.message));
                return
            end
            close(dlg);

            % keep the result and draw it on the map
            app.LayoutResult = struct('cov', cov, 'tree', tree, 'res', res, 'opts', opts);
            drawBusLayout(app, cov, tree, res);

            % summary readings + per-bus table of target vs actual area share
            setOutput(app, busLayout.layoutReport(res, cov, opts));
        end

        % Button pushed function: AddRegionButton
        function AddRegionButtonPushed(app, event)
            % Current map view is what gets saved as the new region
            [latlim, lonlim] = geolimits(app.MapAxes);

            % Existing region names (read from file so the check is up to date)
            S = load(app.RegionFile);
            existingNames = string(S.regions.Properties.RowNames);

            % Build the dialog box, centred over the app
            w = 320; h = 130;
            appPos = app.UIFigure.Position;
            dlg = uifigure('Name', 'Add Region', 'WindowStyle', 'modal', 'Resize', 'off', ...
                'Position', [appPos(1) + (appPos(3) - w)/2, appPos(2) + (appPos(4) - h)/2, w, h]);
            g = uigridlayout(dlg, [3 3]);
            g.RowHeight = {22, 22, 30};
            g.ColumnWidth = {'1x', 80, 80};

            lbl = uilabel(g, 'Text', 'Name for the new region (the current map view):');
            lbl.Layout.Row = 1; lbl.Layout.Column = [1 3];

            nameField = uieditfield(g, 'text', 'Placeholder', 'e.g. Gold Coast');
            nameField.Layout.Row = 2; nameField.Layout.Column = [1 3];

            cancelButton = uibutton(g, 'Text', 'Cancel', 'ButtonPushedFcn', @(~,~) onCancel());
            cancelButton.Layout.Row = 3; cancelButton.Layout.Column = 2;

            addButton = uibutton(g, 'Text', 'Add', 'ButtonPushedFcn', @(~,~) onAdd());
            addButton.Layout.Row = 3; addButton.Layout.Column = 3;

            dlg.CloseRequestFcn = @(~,~) onCancel();   % closing the window = cancel
            focus(nameField);

            % Wait until the user presses Add (with a valid name) or Cancel
            regionName = "";
            uiwait(dlg);
            if isvalid(dlg)
                delete(dlg);
            end

            % Cancelled: nothing happens
            if regionName == ""
                return
            end

            % Add the current limits to RegionLimits.mat under the new name
            newRow = table(latlim(1), latlim(2), lonlim(1), lonlim(2), ...
                'VariableNames', {'LatMin', 'LatMax', 'LonMin', 'LonMax'}, ...
                'RowNames', {char(regionName)});
            S.regions = [S.regions; newRow];
            save(app.RegionFile, '-struct', 'S');

            % Update the app so the new region can be loaded straight away
            app.RegionTable = S.regions;
            refreshRegionList(app);
            setOutput(app, sprintf('Added region "%s"', regionName));

            function onCancel()
                uiresume(dlg);
            end

            function onAdd()
                name = strtrim(string(nameField.Value));
                % catch: no name entered
                if name == ""
                    uialert(dlg, 'Please type a name for the new region.', 'No name entered');
                    return
                end
                % catch: name already used
                if any(strcmpi(name, existingNames))
                    uialert(dlg, sprintf('A region called "%s" already exists. Please choose a different name.', name), ...
                        'Name already used');
                    return
                end
                % catch: characters that can't be used in the region's cache file name
                if contains(name, ["\", "/", ":", "*", "?", """", "<", ">", "|"])
                    uialert(dlg, 'The name can''t contain any of these characters: \ / : * ? " < > |', ...
                        'Invalid name');
                    return
                end
                regionName = name;
                uiresume(dlg);
            end
        end

        % Button pushed function: SettingsButton
        function SettingsButtonPushed(app, event)
            % Open the bus-partition settings dialog (ui/busLayoutSettingsDialog.m).
            % It does not block: the app stays responsive while it is open, and
            % pressing Apply calls settingsApplied with the new settings.
            if ~isempty(app.SettingsDialog) && isvalid(app.SettingsDialog)
                figure(app.SettingsDialog);     % already open: bring it to the front
                return
            end
            app.SettingsDialog = busLayoutSettingsDialog(app.LayoutOptions, app.UIFigure, ...
                @(opts) settingsApplied(app, opts));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'EVCS Planner';
            app.MainGrid = uigridlayout(app.UIFigure);
            app.ControlsPanel = uipanel(app.MainGrid);
            app.ControlsPanelGrid = uigridlayout(app.ControlsPanel);
            app.ControlsGrid = uigridlayout(app.ControlsPanelGrid);
            app.RegionButtonsGrid = uigridlayout(app.ControlsGrid);
            app.LoadRegionButton = uibutton(app.RegionButtonsGrid, 'push');
            app.LoadRegionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRegionButtonPushed, true);
            app.LoadRegionButton.Text = 'Load';
            app.AddRegionButton = uibutton(app.RegionButtonsGrid, 'push');
            app.AddRegionButton.ButtonPushedFcn = createCallbackFcn(app, @AddRegionButtonPushed, true);
            app.AddRegionButton.Text = 'Add Region';
            app.SetCoverageAreaButton = uibutton(app.ControlsGrid, 'push');
            app.SetCoverageAreaButton.ButtonPushedFcn = createCallbackFcn(app, @SetCoverageAreaButtonPushed, true);
            app.SetCoverageAreaButton.Text = 'Set Coverage Area';
            app.RegionSelectGrid = uigridlayout(app.ControlsGrid);
            app.RegionLabel = uilabel(app.RegionSelectGrid);
            app.RegionLabel.Text = 'Region:';
            app.RegionSelectionDropDown = uidropdown(app.RegionSelectGrid);
            app.RegionSelectionDropDown.Items = {};
            app.RegionSelectionDropDown.Placeholder = 'Select a region...';
            app.RegionSelectionDropDown.Value = {};
            app.PlaceSubstationFeederButton = uibutton(app.ControlsGrid, 'push');
            app.PlaceSubstationFeederButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceSubstationFeederButtonPushed, true);
            app.PlaceSubstationFeederButton.Text = 'Place Substation Feeder';
            app.PartitionButtonsGrid = uigridlayout(app.ControlsGrid);
            app.CreateBusPartitionsButton = uibutton(app.PartitionButtonsGrid, 'push');
            app.CreateBusPartitionsButton.ButtonPushedFcn = createCallbackFcn(app, @CreateBusPartitionsButtonPushed, true);
            app.CreateBusPartitionsButton.Text = 'Create Bus Partitions';
            app.SettingsButton = uibutton(app.PartitionButtonsGrid, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Icon = fullfile(pathToMLAPP, 'ui', 'icons', 'settings-cog.svg');
            app.SettingsButton.Tooltip = {'Bus partition settings'};
            app.SettingsButton.Text = '';
            app.ControlsLabel = uilabel(app.ControlsPanelGrid);
            app.ControlsLabel.Text = 'Controls';
            app.MapWorkspacePanel = uipanel(app.MainGrid);
            app.MapWorkspaceGrid = uigridlayout(app.MapWorkspacePanel);
            app.MapWorkspaceLabel = uilabel(app.MapWorkspaceGrid);
            app.MapWorkspaceLabel.Text = 'Map Workspace';
            app.OutputPanel = uipanel(app.MainGrid);
            app.OutputGrid = uigridlayout(app.OutputPanel);
            app.OutputLabel = uilabel(app.OutputGrid);
            app.OutputLabel.Text = 'Output';
            app.OutputBoxPanel = uipanel(app.OutputGrid);
            app.OutputBoxGrid = uigridlayout(app.OutputBoxPanel);
            app.OutputText = uilabel(app.OutputBoxGrid);
            app.OutputText.Text = '';
            app.OutputDisplay = uihtml(app.OutputBoxGrid);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EvcsPlanner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end