classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        LeftGrid                   matlab.ui.container.GridLayout
        ControlsLabel              matlab.ui.control.Label
        ControlsGrid               matlab.ui.container.GridLayout
        CreateBusPartitionsButton  matlab.ui.control.Button
        SetCoverageAreaButton      matlab.ui.control.Button
        GridLayout2                matlab.ui.container.GridLayout
        LoadButton                 matlab.ui.control.Button
        BrowseButton               matlab.ui.control.Button
        CenterPanel                matlab.ui.container.Panel
        CenterGrid                 matlab.ui.container.GridLayout
        MapWorkspaceLabel          matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        RightGrid                  matlab.ui.container.GridLayout
        OutputLabel                matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        GeographicAxesObject matlab.graphics.axis.GeographicAxes % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create geographic axes
            app.GeographicAxesObject = geoaxes(app.CenterGrid, ...
                NextPlot="add",Basemap="streets-dark", ...
                MapCenter=[37.1 -119.5],ZoomLevel=5.7);

            % Put geographic axes in row 2
            app.GeographicAxesObject.Layout.Row = 2;
            app.GeographicAxesObject.Layout.Column = 1;

            % Disable panning and zooming (since R2024a)
            app.GeographicAxesObject.InteractionOptions.PanSupported = "off";
            app.GeographicAxesObject.InteractionOptions.ZoomSupported = "off";
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftGrid = uigridlayout(app.LeftPanel);
            app.ControlsGrid = uigridlayout(app.LeftGrid);
            app.GridLayout2 = uigridlayout(app.ControlsGrid);
            app.BrowseButton = uibutton(app.GridLayout2, 'push');
            app.BrowseButton.Text = 'Browse...';
            app.LoadButton = uibutton(app.GridLayout2, 'push');
            app.LoadButton.Text = 'Load';
            app.SetCoverageAreaButton = uibutton(app.ControlsGrid, 'push');
            app.SetCoverageAreaButton.Text = 'Set Coverage Area';
            app.CreateBusPartitionsButton = uibutton(app.ControlsGrid, 'push');
            app.CreateBusPartitionsButton.Text = 'Create Bus Partitions';
            app.ControlsLabel = uilabel(app.LeftGrid);
            app.ControlsLabel.Text = 'Controls';
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterGrid = uigridlayout(app.CenterPanel);
            app.MapWorkspaceLabel = uilabel(app.CenterGrid);
            app.MapWorkspaceLabel.Text = 'Map Workspace';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightGrid = uigridlayout(app.RightPanel);
            app.OutputLabel = uilabel(app.RightGrid);
            app.OutputLabel.Text = 'Output';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end